classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        pbOpenSerial       matlab.ui.control.StateButton
        Button_Send        matlab.ui.control.Button
        DropDownLabel      matlab.ui.control.Label
        ppCOM              matlab.ui.control.DropDown
        Lamp               matlab.ui.control.Lamp
        Label_3            matlab.ui.control.Label
        Temp               matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        Humi               matlab.ui.control.NumericEditField
        CO2EditFieldLabel  matlab.ui.control.Label
        CO2                matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        Baud               matlab.ui.control.DropDown
        Label_8            matlab.ui.control.Label
        Gauge_Temp         matlab.ui.control.Gauge
        Label_9            matlab.ui.control.Label
        Gauge_Humi         matlab.ui.control.SemicircularGauge
        CO2Label           matlab.ui.control.Label
        Gauge_CO2          matlab.ui.control.LinearGauge
        Label_10           matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        Label_12           matlab.ui.control.Label
        Lamp_Temp          matlab.ui.control.Lamp
        Label_17           matlab.ui.control.Label
        Lamp_Humi          matlab.ui.control.Lamp
        CO2LampLabel       matlab.ui.control.Label
        Lamp_CO2           matlab.ui.control.Lamp
        Label_14           matlab.ui.control.Label
        TextArea_Temp      matlab.ui.control.TextArea
        CO2Label_2         matlab.ui.control.Label
        TextArea_CO2       matlab.ui.control.TextArea
        Label_19           matlab.ui.control.Label
        TextArea_Humi      matlab.ui.control.TextArea
        PPMLabel           matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        Image              matlab.ui.control.Image
        Image2             matlab.ui.control.Image
        Image3             matlab.ui.control.Image
        Image4             matlab.ui.control.Image
        Label_15           matlab.ui.control.Label
        Lamp_Temp_2        matlab.ui.control.Lamp
        Label_16           matlab.ui.control.Label
        TextArea_Temp_2    matlab.ui.control.TextArea
        Label_18           matlab.ui.control.Label
        Lamp_Humi_2        matlab.ui.control.Lamp
        Label_20           matlab.ui.control.Label
        TextArea_Humi_2    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        COM;    % 端口号
        s  ;    %端口设置句柄
        RX_num; %接收统计
        TX_num; %发送统计
        RX_once;%一次接收的数据
        RX_Date;%接收的所以数据
        is_open;%是否打开串口标志位
        
    end
    
    methods (Access = public)
        function  EveBytesAvailableFcn(app, src, event)
            global i;
            n = src.BytesAvailable;%获取接收到的字符个数
            if n>0%n>0才继续执行，因为0为0也会触发中断
                app.RX_num=app.RX_num+n;
                %app.Label_RX.Text=num2str(app.RX_num);%将数字转化为字符串输出
                app.RX_once=fread(src,n,'uchar');%读取函数，读取后矩阵为一列
                app.RX_Date =strcat(app.RX_Date, app.RX_once');%字符串拼接，需要转置化为一行
                %app.ReceiveView.Value= app.RX_Date;%textarea的设置格式为cell,或单行字符串
                
                temp=floor(str2double(app.RX_Date)/1000000)*0.1;
                app.Temp.Value=temp;
                app.Gauge_Temp.Value=temp;
                plot(app.UIAxes,i,temp,'r.');
%                 if i==20
%                     i=0;
%                     cla(app.UIAxes);
%                 else
%                     hold(app.UIAxes,'on');   % 这样就可以hold住了
%                     i=i+1;
%                 end
                if temp>=str2double(app.TextArea_Temp.Value)
                    app.Lamp_Temp.Color="1.00,0.00,0.00";
                else
                    app.Lamp_Temp.Color="0.00,1.00,0.00";
                end
                if temp<=str2double(app.TextArea_Temp_2.Value)
                    app.Lamp_Temp_2.Color="1.00,0.00,0.00";
                else
                    app.Lamp_Temp_2.Color="0.00,1.00,0.00";
                end
                
                humi=mod((floor(str2double(app.RX_Date)/1000)),1000)*0.1;
                app.Humi.Value=humi;
                app.Gauge_Humi.Value=humi;
                plot(app.UIAxes,i,temp,'r.');
                plot(app.UIAxes_2,i,humi,'b.');
                if i==20
                    i=0;
                    cla(app.UIAxes);
                    cla(app.UIAxes_2);
                else
                    hold(app.UIAxes,'on');   % 这样就可以hold住了
                    hold(app.UIAxes_2,'on');   % 这样就可以hold住了
                    i=i+1;
                end
                if humi>=str2double(app.TextArea_Humi.Value)
                    app.Lamp_Humi.Color="1.00,0.00,0.00";
                else
                    app.Lamp_Humi.Color="0.00,1.00,0.00";
                end
                if humi<=str2double(app.TextArea_Humi_2.Value)
                    app.Lamp_Humi_2.Color="1.00,0.00,0.00";
                else
                    app.Lamp_Humi_2.Color="0.00,1.00,0.00";
                end
                
                co2=mod(str2double(app.RX_Date),1000);
                app.CO2.Value=co2;
                app.Gauge_CO2.Value=co2;
                if co2>=str2double(app.TextArea_CO2.Value)
                    app.Lamp_CO2.Color="1.00,0.00,0.00";
                else
                    app.Lamp_CO2.Color="0.00,1.00,0.00";
                end
                
                app.RX_Date ='';
                %app.ReceiveView.Value= app.RX_Date;
                %app.TX_num=0;
                %app.RX_num=0;
                %app.Label_TX.Text=num2str(app.TX_num);
                %app.Label_RX.Text=num2str(app.RX_num);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global i;
            i=0;
            app.RX_num=0;
            app.TX_num=0;
            app.is_open=0;
            scoms = instrfind;%获取占用的串口号
            if scoms ~= 0%如果存在则关闭，否则不能打开
                stopasync(scoms);
                fclose(scoms);
                delete(scoms);
            end
        end

        % Button pushed function: Button_Send
        function Button_SendPushed(app, event)
            %val=app.transmitView.Value;
            val=strcat(strcat(app.TextArea_Temp.Value,app.TextArea_Humi.Value),app.TextArea_CO2.Value);
            
            %val=num2str(app.EditField_Temp.Value);
            if ~isempty(val{1})%textarea控件是cell格式，获取需要用{1}
                app.TX_num=app.TX_num+length(val{1});
                %app.Label_TX.Text=num2str(app.TX_num);
                fwrite(app.s, char(val), 'uint8', 'async');%需要将val转化为char
            end
        end

        % Value changed function: pbOpenSerial
        function pbOpenSerialValueChanged2(app, event)
            
            app.COM=get(app.ppCOM,'Value');
            if strcmp(get(app.pbOpenSerial,'Text'),'打开串口')
                try
                    app.s=serial(app.COM);
                    app.s.BaudRate=str2num(app.Baud.Value);%设置波特率
                    %app.s.BaudRate=115200;
                    app.s.DataBits=8;%设置数据长度
                    app.s.StopBits=1;%设置停止位长度
                    app.s.InputBufferSize=1024000;%设置输入缓冲区大小为1M
                    app.s.BytesAvailableFcnMode='byte';  %串口事件回调设置
                    %  app.s.Terminator='CR/LF';
                    app.s.BytesAvailableFcnCount=1; %输入缓冲区存在10个字节触发回调函数
                    app.s.BytesAvailableFcn={@app.EveBytesAvailableFcn};%回调函数的指定
                    fopen(app.s);%打开串口
                    app.is_open=1;
                    app.pbOpenSerial.Text='关闭串口';
                    app.Lamp.Color=[0 1 0];
                catch err
                    msgbox('打开失败');
                end
            else
                try
                    fclose(app.s);
                    app.pbOpenSerial.Text='打开串口';
                    app.Lamp.Color=[0.15 0.15 0.15];
                catch err
                    msgbox('关闭失败');
                end
                delete(app.s);
                app.is_open=0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function Button_ClearPushed(app, event)
            %             app.RX_Date ='';
            %             app.ReceiveView.Value= app.RX_Date;
            %             app.TX_num=0;
            %             app.RX_num=0;
            %             app.Label_TX.Text=num2str(app.TX_num);
            %             app.Label_RX.Text=num2str(app.RX_num);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '自动1804汪雨蝶课设';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.pbOpenSerial = uibutton(app.UIFigure, 'state');
            app.pbOpenSerial.ValueChangedFcn = createCallbackFcn(app, @pbOpenSerialValueChanged2, true);
            app.pbOpenSerial.Text = '打开串口';
            app.Button_Send = uibutton(app.UIFigure, 'push');
            app.Button_Send.ButtonPushedFcn = createCallbackFcn(app, @Button_SendPushed, true);
            app.Button_Send.Text = '发送';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '串口';
            app.ppCOM = uidropdown(app.UIFigure);
            app.ppCOM.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.ppCOM.Value = 'COM5';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '温度';
            app.Temp = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '湿度';
            app.Humi = uieditfield(app.UIFigure, 'numeric');
            app.CO2EditFieldLabel = uilabel(app.UIFigure);
            app.CO2EditFieldLabel.Text = 'CO2浓度';
            app.CO2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '波特率';
            app.Baud = uidropdown(app.UIFigure);
            app.Baud.Items = {'9600', '57600', '115200'};
            app.Baud.Editable = 'on';
            app.Baud.Value = '115200';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '温度';
            app.Gauge_Temp = uigauge(app.UIFigure, 'circular');
            app.Gauge_Temp.Limits = [0 50];
            app.Gauge_Temp.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '湿度';
            app.Gauge_Humi = uigauge(app.UIFigure, 'semicircular');
            app.CO2Label = uilabel(app.UIFigure);
            app.CO2Label.Text = 'CO2浓度';
            app.Gauge_CO2 = uigauge(app.UIFigure, 'linear');
            app.Gauge_CO2.Limits = [0 1000];
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '℃';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '温度过高指示灯';
            app.Lamp_Temp = uilamp(app.UIFigure);
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '湿度过高指示灯';
            app.Lamp_Humi = uilamp(app.UIFigure);
            app.CO2LampLabel = uilabel(app.UIFigure);
            app.CO2LampLabel.Text = 'CO2浓度指示灯';
            app.Lamp_CO2 = uilamp(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '温度最大值';
            app.TextArea_Temp = uitextarea(app.UIFigure);
            app.TextArea_Temp.Value = {'45'};
            app.CO2Label_2 = uilabel(app.UIFigure);
            app.CO2Label_2.Text = 'CO2阈值';
            app.TextArea_CO2 = uitextarea(app.UIFigure);
            app.TextArea_CO2.Value = {'800'};
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '湿度最大值';
            app.TextArea_Humi = uitextarea(app.UIFigure);
            app.TextArea_Humi.Value = {'40'};
            app.PPMLabel = uilabel(app.UIFigure);
            app.PPMLabel.Text = 'PPM';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '温度')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 50];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '湿度')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XLim = [0 20];
            app.UIAxes_2.YLim = [0 100];
            app.UIAxes_2.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.UIAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UIAxes_2.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes_2.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '11.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '3.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '55.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '333.png';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '温度过低指示灯';
            app.Lamp_Temp_2 = uilamp(app.UIFigure);
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '温度最小值';
            app.TextArea_Temp_2 = uitextarea(app.UIFigure);
            app.TextArea_Temp_2.Value = {'10'};
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '湿度过低指示灯';
            app.Lamp_Humi_2 = uilamp(app.UIFigure);
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '湿度最小值';
            app.TextArea_Humi_2 = uitextarea(app.UIFigure);
            app.TextArea_Humi_2.Value = {'5'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end